classdef bioprinter_SG_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        ConnectTab                 matlab.ui.container.Tab
        ConnectionGridLayout       matlab.ui.container.GridLayout
        ChoosePortDropDown         matlab.ui.control.DropDown
        ChoosePortDropDownLabel    matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        StatusLampLabel            matlab.ui.control.Label
        DisconnectButton           matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        ManualTab                  matlab.ui.container.Tab
        SpeedSpinner               matlab.ui.control.Spinner
        SpeedSpinnerLabel          matlab.ui.control.Label
        MoveExtrudeDirectionPanel  matlab.ui.container.Panel
        S3BackButton               matlab.ui.control.Button
        S3ExtrudeButton            matlab.ui.control.Button
        S2BackButton               matlab.ui.control.Button
        S2ExtrudeButton            matlab.ui.control.Button
        S1BackButton               matlab.ui.control.Button
        S1ExtrudeButton            matlab.ui.control.Button
        ZDownButton                matlab.ui.control.Button
        ZUpButton                  matlab.ui.control.Button
        YForwardButton             matlab.ui.control.Button
        YBackButton                matlab.ui.control.Button
        XLeftButton                matlab.ui.control.Button
        XRightButton               matlab.ui.control.Button
        MoveExtrudeDistancemmButtonGroup  matlab.ui.container.ButtonGroup
        DistanceEditField          matlab.ui.control.EditField
        DistanceEditFieldLabel     matlab.ui.control.Label
        distance100                matlab.ui.control.ToggleButton
        distance10                 matlab.ui.control.ToggleButton
        distance1                  matlab.ui.control.ToggleButton
        distance01                 matlab.ui.control.ToggleButton
        PrintTab                   matlab.ui.container.Tab
        PrintProgressLabel         matlab.ui.control.Label
        ProgressBar                matlab.ui.control.LinearGauge
        PrintButton                matlab.ui.control.Button
        UploadgcodeButton          matlab.ui.control.Button
        gCodePlot                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % private properties
    properties (Access = private)
        Stopped = 0
        DonePrinting = false % Description
    end

    % public properties
    properties (Access = public)
        arduino; % Arduino object

        XMotor = 0b00; % define XMotor as 0 in binary
        YMotor = 0b01; % define YMotor as 1 in binary
        ZMotor = 0b10; % define ZMotor as 2 in binary
        syringe1 = 0b11; % define syringe 1 as 3 in binary
        syringe2 = 0b100; % define syringe 2 as 4 in binary
        syringe3 = 0b101; % define syringe 3 as 5 in binary

        ports; % intialize variable to store available ports
        selectedPort; % initialize variable for the selected port

        moveDistance; % distance in which to move

        % set the speed of the movement, should change to be more variables
        movementSpeed;

        % XYZ conversion factor
        xStepFactor = 319; %320 position - 100ms
        yStepFactor = 321;
        zStepFactor = 320;
        % Extrusion conversion factor
        extrusionStepFactor = 3200;

        % storage variables for the locations of the printer head
        XLocation;
        YLocation;
        ZLocation;
        S1Location;
        S2Location;
        S3Location;

        stringArray;
        lineRead;

        plot;
    end

    % private functions
    methods (Access = private)
    end

    % public functions
    methods (Access = private)
        %------------------------------------------------------------------

        function readSerialData(app,src,~)
            data = strtrim(readline(src));
            app.lineRead = 1;
            if contains(data, "Stop")
                flush(app.arduino)
                app.Stopped = 1;
            elseif contains(data, "Read")
                app.lineRead = [];
            elseif strcmp(data,"D")
                app.DonePrinting = true;
            end
            disp(data);
        end

        %------------------------------------------------------------------
        % function [xCoords, yCoords, cumulativeZ, stringArray] = read_gcode(app,filename)
        %     % Open and read a .gcode file to extract X, Y, Z, and E values
        %     parsedLines = {};
        %     lineIndex = 1;
        %
        %     % Open the G-code file
        %     fileID = fopen(filename, 'r');
        %     currentLine = '';
        %
        %     while ischar(currentLine)
        %         currentLine = fgetl(fileID);
        %         if ~ischar(currentLine)
        %             break;
        %         end
        %         if ischar(currentLine) && ~isempty(currentLine)
        %             isValidLine = ~startsWith(currentLine, 'M') && ... % Exclude M-codes
        %                 ~startsWith(currentLine, 'G9') && ... % Exclude G9 codes
        %                 ~startsWith(currentLine, 'G2'); % Exclude G2 codes
        %
        %             if isValidLine
        %                 parsedLines{lineIndex} = currentLine;
        %                 lineIndex = lineIndex + 1;
        %             end
        %         end
        %     end
        %     fclose(fileID);
        %
        %     startIndex = find(strcmp(parsedLines, ';LAYER:1'));
        %     parsedLines = parsedLines(startIndex:end);
        %     index = cellfun(@(x) ~contains(x, ';'), parsedLines);
        %     parsedLines = parsedLines(index);
        %
        %     % Parse each G-code line and build data array
        %     gcodeData = [];
        %     for lineNumber = 1:(length(parsedLines))
        %         currentLine = parsedLines{lineNumber};
        %         parsedData = Read_Gcode_string(app,currentLine);
        %         gcodeData = [gcodeData; parsedData];
        %     end
        %
        %     % Extract G-code, X, Y, Z, and E values
        %     gCodes = real(gcodeData(:, 1));
        %     xValues = gcodeData(:, 3);
        %     yValues = gcodeData(:, 4);
        %     zValues = gcodeData(:, 5);
        %     extrusionValues = gcodeData(:, 6);
        %
        %     % Filter lines based on criteria
        %     hasXYZData = imag(xValues) + imag(yValues) + imag(zValues) > 0;
        %     validLines = (real(gCodes) < 2) & hasXYZData;
        %
        %     % Extract XYZ coordinates and extrusion states
        %     xyzCoordinates = real(gcodeData(validLines, 3:5));
        %     extrusionValues = gcodeData(validLines, 6);
        %
        %     xCoords = xyzCoordinates(:, 1);
        %     yCoords = xyzCoordinates(:, 2);
        %     zCoords = xyzCoordinates(:, 3);
        %
        %     % Calculate cumulative maximum of Z values
        %     cumulativeZ = zeros(size(zCoords));
        %     maxZ = 0;
        %     for i = 1:length(zCoords)
        %         if zCoords(i) > maxZ
        %             maxZ = zCoords(i);
        %         end
        %         cumulativeZ(i) = maxZ;
        %     end
        %
        %     % Process E values to indicate extrusion states
        %     % extrusionState = imag(extrusionValues) & 1;
        %
        %     extrusion = real(extrusionValues) - real(extrusionValues(1));
        %     % extrusion(extrusion < 0) = 0;
        %
        %     for i = 2:length(extrusion)
        %         if extrusion(i) < 0
        %             extrusion(i) = extrusion(i-1);
        %         end
        %     end
        %
        %     stringArray =[];
        %
        %     for n = 1:length(zCoords)
        %         str = sprintf('%g ', [gCodes(n),xCoords(n),yCoords(n),cumulativeZ(n),extrusion(n)]);
        %         str = strtrim(str);
        %         stringArray = [stringArray; {str}];
        %     end
        % end
        %
        % function [parsedData] = Read_Gcode_string(app,gcodeLine)
        %     % Parse a single line of G-code into structured data
        %     tokens = split(gcodeLine);
        %     numTokens = length(tokens);
        %
        %     % Define G-code patterns
        %     patterns = {'G%d', 'F%f', 'X%f', 'Y%f', 'Z%f', 'E%f'};
        %     parsedData = zeros(numTokens, 6);
        %
        %     % Parse each token based on patterns
        %     for tokenIndex = 1:numTokens
        %         for patternIndex = 1:length(patterns)
        %             value = sscanf(tokens{tokenIndex}, patterns{patternIndex});
        %             if ~isempty(value)
        %                 parsedData(tokenIndex, patternIndex) = value + 1i; % Use imaginary part as a flag
        %             end
        %         end
        %     end
        %
        %     % Summarize parsed data if multiple tokens
        %     if numTokens > 1
        %         parsedData = sum(parsedData);
        %     end
        % end

        function [xCoords, yCoords, cumulativeZ, stringArray] = read_gcode(app,filename)
            % Open the G-code file
            fileID = fopen(filename, 'r');
            parsedLines = {};
            lineIndex = 1;

            currentLine = fgetl(fileID);
            while ischar(currentLine)
                if ~isempty(currentLine)
                    isValidLine = ~startsWith(currentLine, 'M') && ...
                        ~startsWith(currentLine, 'G9') && ...
                        ~startsWith(currentLine, 'G2') && ...
                        contains(currentLine, {'G0', 'G1',';LAYER' });  % Only movement lines
                    if isValidLine
                        parsedLines{lineIndex} = currentLine;
                        lineIndex = lineIndex + 1;
                    end
                end
                currentLine = fgetl(fileID);
            end
            fclose(fileID);

            % Optional: start from layer 1
            startIndex = find(strcmp(parsedLines, ';LAYER:1'), 1);
            if ~isempty(startIndex)
                parsedLines = parsedLines(startIndex:end);
            end

            % Initialize last known coordinates
            lastX = 0; lastY = 0; lastZ = 0; lastE = 0;
            xCoords = []; yCoords = []; zCoords = []; eCoords = []; gCodes = [];

            % Parse each line and build coordinate list
            for i = 1:length(parsedLines)
                line = parsedLines{i};
                tokens = split(line);
                parsedData = zeros(1, 6);  % G, F, X, Y, Z, E
                patterns = {'G%d', 'F%f', 'X%f', 'Y%f', 'Z%f', 'E%f'};

                for t = 1:length(tokens)
                    for p = 1:length(patterns)
                        value = sscanf(tokens{t}, patterns{p});
                        if ~isempty(value)
                            parsedData(p) = value + 1i;  % Store imaginary flag
                        end
                    end
                end

                gcode = real(parsedData(1));
                xVal = real(parsedData(3)); yVal = real(parsedData(4));
                zVal = real(parsedData(5)); eVal = real(parsedData(6));

                if imag(parsedData(3)), lastX = xVal; end
                if imag(parsedData(4)), lastY = yVal; end
                if imag(parsedData(5)), lastZ = zVal; end
                if imag(parsedData(6)), lastE = eVal; end

                xCoords(end+1,1) = lastX;
                yCoords(end+1,1) = lastY;
                zCoords(end+1,1) = lastZ;
                eCoords(end+1,1) = lastE;
                gCodes(end+1,1) = gcode;
            end

            % Calculate cumulative Z
            cumulativeZ = zeros(size(zCoords));
            maxZ = 0;
            for i = 1:length(zCoords)
                if zCoords(i) > maxZ
                    maxZ = zCoords(i);
                end
                cumulativeZ(i) = maxZ;
            end

            % Normalize extrusion values
            extrusion = eCoords - eCoords(1);
            for i = 2:length(extrusion)
                if extrusion(i) < 0
                    extrusion(i) = extrusion(i-1);
                end
            end

            % Build string array
            stringArray = [];
            for n = 1:length(xCoords)
                str = sprintf('%g %g %g %g %g', gCodes(n), xCoords(n), yCoords(n), cumulativeZ(n), extrusion(n));
                stringArray = [stringArray; {str}];
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % find all the available serial ports
            app.ports = serialportlist;
            % populate the drop down list with the available ports
            app.ChoosePortDropDown.Items = app.ports;
            app.selectedPort = app.ChoosePortDropDown.Value;
            app.moveDistance = 0;
            app.movementSpeed = app.SpeedSpinner.Value;

            app.XLocation = 0;
            app.YLocation = 0;
            app.ZLocation = 0;
            app.S1Location = 0;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % clear the app and close it out
            delete(app)
            clear all;
            % clc;
        end

        % Value changed function: ChoosePortDropDown
        function ChoosePortDropDownValueChanged(app, event)
            % set the selected port to be the chosen drop down item
            app.selectedPort = char(app.ChoosePortDropDown.Value);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.arduino = serialport(app.selectedPort,9600);
            configureCallback(app.arduino,"terminator",@app.readSerialData);

            if ~isempty(app.arduino)
                % change the status lamp color to green
                app.StatusLamp.Color = [0 1 0];
                % disable the connect button when it is connected
                app.ConnectButton.Enable = 'off';
            end

            % after the printer is connected, it is homed, so save the
            % positions as 0 so we can use them as a reference for the
            % gcode
            app.XLocation = 0;
            app.YLocation = 0;
            app.ZLocation = 0;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Flush input and output buffers
            flush(app.arduino);

            % Delete the Arduino object to close the connection
            delete(app.arduino);

            % clear the axis
            cla(app.gCodePlot);
            app.ProgressBar.Value = 0;

            % Clear the object from the workspace
            clear app.arduino;

            % change the color of the status lamp
            app.StatusLamp.Color = [1 0 0];

            % enable the connect button
            app.ConnectButton.Enable = 'on';
        end

        % Selection changed function: MoveExtrudeDistancemmButtonGroup
        function MoveExtrudeDistancemmButtonGroupSelectionChanged(app, event)
            % get the button selected within the distance tab
            selectedButton = app.MoveExtrudeDistancemmButtonGroup.SelectedObject;

            % depending on which button is pressed, set the moveDistance
            switch selectedButton
                case app.distance100
                    app.moveDistance = 100;
                case app.distance10
                    app.moveDistance = 10;
                case app.distance1
                    app.moveDistance = 1;
                case app.distance01
                    app.moveDistance = 0.1;
            end
        end

        % Value changed function: DistanceEditField
        function DistanceEditFieldValueChanged(app, event)
            % if there is a distance field changed, store as moveDistance
            app.moveDistance = str2double(app.DistanceEditField.Value);
        end

        % Button pushed function: XLeftButton
        function XLeftButtonPushed(app, event)
            % app.move(app.make_byte_arr(940, 0, app.XMotor, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1, (app.XLocation + app.moveDistance), app.YLocation, app.ZLocation, app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.XLocation = app.XLocation + app.moveDistance;
        end

        % Button pushed function: XRightButton
        function XRightButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 0, app.XMotor, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1, (app.XLocation - app.moveDistance), app.YLocation, app.ZLocation, app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.XLocation = app.XLocation - app.moveDistance;
        end

        % Button pushed function: YBackButton
        function YBackButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 0, app.YMotor, app.movementSpeed, app.yStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, (app.YLocation - app.moveDistance), app.ZLocation, app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.YLocation = app.YLocation - app.moveDistance;
        end

        % Button pushed function: YForwardButton
        function YForwardButtonPushed(app, event)
            % app.move(app.make_byte_arr(app.moveDistance, 0, app.YMotor, app.movementSpeed, app.yStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, (app.YLocation + app.moveDistance), app.ZLocation, app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.YLocation = app.YLocation + app.moveDistance;
        end

        % Button pushed function: ZUpButton
        function ZUpButtonPushed(app, event)
            % app.move(app.make_byte_arr(app.moveDistance, 0, app.ZMotor, app.movementSpeed, app.zStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, app.YLocation, (app.ZLocation + app.moveDistance), app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.ZLocation = app.ZLocation + app.moveDistance;
        end

        % Button pushed function: ZDownButton
        function ZDownButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 0, app.ZMotor, app.movementSpeed, app.zStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, app.YLocation, (app.ZLocation - app.moveDistance), app.S1Location);
            flush(app.arduino)
            writeline(app.arduino, str);
            app.ZLocation = app.ZLocation - app.moveDistance;
        end

        % Button pushed function: S1ExtrudeButton
        function S1ExtrudeButtonPushed(app, event)
            % app.move(app.make_byte_arr(app.moveDistance, 1, app.syringe1, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, app.YLocation, app.ZLocation, (app.S1Location + app.moveDistance));
            flush(app.arduino)
            writeline(app.arduino, str);
            app.S1Location = app.S1Location + app.moveDistance;
        end

        % Button pushed function: S1BackButton
        function S1BackButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 1, app.syringe1, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
            str = sprintf('%g %g %g %g %g', 1,app.XLocation, app.YLocation, app.ZLocation, (app.S1Location - app.moveDistance));
            flush(app.arduino)
            writeline(app.arduino, str);
            app.S1Location = app.S1Location - app.moveDistance;
        end

        % Button pushed function: S2ExtrudeButton
        function S2ExtrudeButtonPushed(app, event)
            % app.move(app.make_byte_arr(app.moveDistance, 1, app.syringe2, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
        end

        % Button pushed function: S2BackButton
        function S2BackButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 1, app.syringe2, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
        end

        % Button pushed function: S3ExtrudeButton
        function S3ExtrudeButtonPushed(app, event)
            % app.move(app.make_byte_arr(app.moveDistance, 1 , app.syringe3, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
        end

        % Button pushed function: S3BackButton
        function S3BackButtonPushed(app, event)
            % app.move(app.make_byte_arr(-app.moveDistance, 1, app.syringe3, app.movementSpeed, app.xStepFactor, app.extrusionStepFactor));
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            app.movementSpeed = app.SpeedSpinner.Value;
        end

        % Button pushed function: UploadgcodeButton
        function UploadgcodeButtonPushed(app, event)
            % prompt user to get the file
            filename = uigetfile('*.gcode');
            % read the file and output the coordintates
            [xCoords, yCoords, cumulativeZ, app.stringArray] = read_gcode(app, filename);

            app.plot = plot3(app.gCodePlot, xCoords, yCoords, cumulativeZ);
            title(app.gCodePlot, 'Print Preview');
        end

        % Button pushed function: PrintButton
        function PrintButtonPushed(app, event)
            app.ProgressBar.Value = 0;

            % Prep the progress bar
            TotalPoints = size(app.stringArray, 1);
            PointsPrintedArray = zeros(1, TotalPoints);

            for i = 1:length(app.stringArray)
                if app.Stopped
                    app.Stopped = 0;
                    disp("Stopping...");
                    break;
                end

                delete(findall(app.plot, 'Type', 'datatip')); % Remove previous datatips
                datatip(app.plot, 'DataIndex', i);

                % Retry sending and waiting for confirmation
                maxRetries = 5;
                retryDelay = 0.5; % seconds
                success = false;

                for attempt = 1:maxRetries
                    try
                        flush(app.arduino);
                        pause(0.01); 
                        writeline(app.arduino, app.stringArray{i});
                        app.DonePrinting = false;

                        timeout = 10; % seconds
                        tic;
                        while ~app.DonePrinting && toc < timeout
                            if app.arduino.NumBytesAvailable > 0
                                try
                                    msg = readline(app.arduino);
                                    % disp("Arduino says: " + msg);
                                    if ischar(msg) || isstring(msg)
                                        if contains(msg, "D")
                                            app.DonePrinting = true;
                                        end
                                    end 
                                catch ME
                                    warning("Error reading from Arduino");
                                end
                            end
                            pause(0.01);
                        end


                        if app.DonePrinting
                            success = true;
                            break;
                        else
                            % disp("Timeout waiting for Arduino response.");
                        end
                    catch ME
                        fprintf("Attempt %d failed: %s\n", attempt, ME.message);
                    end
                    pause(retryDelay);
                    % fprintf("Retrying command %d (attempt %d of %d)...\n", i, attempt + 1, maxRetries);
                end

                if ~success
                    warning("Skipping point %d after %d failed attempts.", i, maxRetries);
                    continue;  % Optionally skip this point if all retries fail
                end

                PointsPrintedArray(i) = 1;
                PointsPrinted = sum(PointsPrintedArray);

                if mod(PointsPrinted, 20) == 0 || PointsPrinted == TotalPoints
                    Progress = (PointsPrinted / TotalPoints) * 100;
                    fprintf("Progress: %.1f%% completed\n", Progress);
                    app.ProgressBar.Value = Progress;
                end
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.ConnectionGridLayout = uigridlayout(app.ConnectTab);
            app.ConnectButton = uibutton(app.ConnectionGridLayout, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ConnectionGridLayout, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLampLabel = uilabel(app.ConnectionGridLayout);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ConnectionGridLayout);
            app.ChoosePortDropDownLabel = uilabel(app.ConnectionGridLayout);
            app.ChoosePortDropDownLabel.Text = 'Choose Port: ';
            app.ChoosePortDropDown = uidropdown(app.ConnectionGridLayout);
            app.ChoosePortDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosePortDropDownValueChanged, true);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.MoveExtrudeDistancemmButtonGroup = uibuttongroup(app.ManualTab);
            app.MoveExtrudeDistancemmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoveExtrudeDistancemmButtonGroupSelectionChanged, true);
            app.MoveExtrudeDistancemmButtonGroup.Title = 'Move/Extrude Distance (mm)';
            app.distance01 = uitogglebutton(app.MoveExtrudeDistancemmButtonGroup);
            app.distance01.Text = '0.1';
            app.distance01.Value = true;
            app.distance1 = uitogglebutton(app.MoveExtrudeDistancemmButtonGroup);
            app.distance1.Text = '1';
            app.distance10 = uitogglebutton(app.MoveExtrudeDistancemmButtonGroup);
            app.distance10.Text = '10';
            app.distance100 = uitogglebutton(app.MoveExtrudeDistancemmButtonGroup);
            app.distance100.Text = '100';
            app.DistanceEditFieldLabel = uilabel(app.MoveExtrudeDistancemmButtonGroup);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.MoveExtrudeDistancemmButtonGroup, 'text');
            app.DistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceEditFieldValueChanged, true);
            app.MoveExtrudeDirectionPanel = uipanel(app.ManualTab);
            app.MoveExtrudeDirectionPanel.Title = 'Move/Extrude Direction';
            app.XRightButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.XRightButton.ButtonPushedFcn = createCallbackFcn(app, @XRightButtonPushed, true);
            app.XRightButton.Text = '+X';
            app.XLeftButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.XLeftButton.ButtonPushedFcn = createCallbackFcn(app, @XLeftButtonPushed, true);
            app.XLeftButton.Text = '-X';
            app.YBackButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.YBackButton.ButtonPushedFcn = createCallbackFcn(app, @YBackButtonPushed, true);
            app.YBackButton.Text = '+Y';
            app.YForwardButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.YForwardButton.ButtonPushedFcn = createCallbackFcn(app, @YForwardButtonPushed, true);
            app.YForwardButton.Text = '-Y';
            app.ZUpButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.ZUpButton.ButtonPushedFcn = createCallbackFcn(app, @ZUpButtonPushed, true);
            app.ZUpButton.Text = '+Z';
            app.ZDownButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.ZDownButton.ButtonPushedFcn = createCallbackFcn(app, @ZDownButtonPushed, true);
            app.ZDownButton.Text = '-Z';
            app.S1ExtrudeButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S1ExtrudeButton.ButtonPushedFcn = createCallbackFcn(app, @S1ExtrudeButtonPushed, true);
            app.S1ExtrudeButton.Text = '+S1';
            app.S1BackButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S1BackButton.ButtonPushedFcn = createCallbackFcn(app, @S1BackButtonPushed, true);
            app.S1BackButton.Text = '-S1';
            app.S2ExtrudeButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S2ExtrudeButton.ButtonPushedFcn = createCallbackFcn(app, @S2ExtrudeButtonPushed, true);
            app.S2ExtrudeButton.Text = '+S2';
            app.S2BackButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S2BackButton.ButtonPushedFcn = createCallbackFcn(app, @S2BackButtonPushed, true);
            app.S2BackButton.Text = '-S2';
            app.S3ExtrudeButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S3ExtrudeButton.ButtonPushedFcn = createCallbackFcn(app, @S3ExtrudeButtonPushed, true);
            app.S3ExtrudeButton.Text = '+S3';
            app.S3BackButton = uibutton(app.MoveExtrudeDirectionPanel, 'push');
            app.S3BackButton.ButtonPushedFcn = createCallbackFcn(app, @S3BackButtonPushed, true);
            app.S3BackButton.Text = '-S3';
            app.SpeedSpinnerLabel = uilabel(app.ManualTab);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.ManualTab);
            app.SpeedSpinner.Limits = [0 15];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.SpeedSpinner.Value = 15;
            app.PrintTab = uitab(app.TabGroup);
            app.PrintTab.Title = 'Print';
            app.gCodePlot = uiaxes(app.PrintTab);
            title(app.gCodePlot, 'Title')
            xlabel(app.gCodePlot, 'X')
            ylabel(app.gCodePlot, 'Y')
            zlabel(app.gCodePlot, 'Z')
            app.UploadgcodeButton = uibutton(app.PrintTab, 'push');
            app.UploadgcodeButton.ButtonPushedFcn = createCallbackFcn(app, @UploadgcodeButtonPushed, true);
            app.UploadgcodeButton.Text = 'Upload .gcode';
            app.PrintButton = uibutton(app.PrintTab, 'push');
            app.PrintButton.ButtonPushedFcn = createCallbackFcn(app, @PrintButtonPushed, true);
            app.PrintButton.Text = 'Print';
            app.ProgressBar = uigauge(app.PrintTab, 'linear');
            app.PrintProgressLabel = uilabel(app.PrintTab);
            app.PrintProgressLabel.Text = 'Print Progress:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bioprinter_SG_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end